classdef fourth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Image                matlab.ui.control.Image
        MaharashtraButton    matlab.ui.control.Button
        Image2               matlab.ui.control.Image
        AndhrapradeshButton  matlab.ui.control.Button
        Image3               matlab.ui.control.Image
        BiharButton          matlab.ui.control.Button
        Image4               matlab.ui.control.Image
        AsaamButton          matlab.ui.control.Button
        Image5               matlab.ui.control.Image
        GujratButton         matlab.ui.control.Button
        Image6               matlab.ui.control.Image
        UttarpradeshButton   matlab.ui.control.Button
        KarnatakaButton      matlab.ui.control.Button
        Image8               matlab.ui.control.Image
        GoaButton            matlab.ui.control.Button
        Image9               matlab.ui.control.Image
        PanjabButton         matlab.ui.control.Button
        Image10              matlab.ui.control.Image
        DelhiButton          matlab.ui.control.Button
        Image11              matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MaharashtraButton
        function MaharashtraButtonPushed(app, event)

            third < matlab.apps.AppBase;
        end

        % Button pushed function: AsaamButton
        function AsaamButtonPushed(app, event)
            third < matlab.apps.AppBase;
        end

        % Button pushed function: BiharButton
        function BiharButtonPushed(app, event)
            third < matlab.apps.AppBase;
        end

        % Button pushed function: PanjabButton
        function PanjabButtonPushed(app, event)
            third < matlab.apps.AppBase;
        end

        % Button pushed function: GujratButton
        function GujratButtonPushed(app, event)
            gujrat < matlab.apps.AppBase;
        end

        % Button pushed function: UttarpradeshButton
        function UttarpradeshButtonPushed(app, event)
            Uttarpradesh < matlab.apps.AppBase;
        end

        % Button pushed function: AndhrapradeshButton
        function AndhrapradeshButtonPushed(app, event)
            third < matlab.apps.AppBase;
        end

        % Button pushed function: GoaButton
        function GoaButtonPushed(app, event)
            third < matlab.apps.AppBase;
        end

        % Button pushed function: KarnatakaButton
        function KarnatakaButtonPushed(app, event)
            third < matlab.apps.AppBase;
        end

        % Button pushed function: DelhiButton
        function DelhiButtonPushed(app, event)
            delhi < matlab.apps.AppBase;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'maharashtra.png';
            app.MaharashtraButton = uibutton(app.UIFigure, 'push');
            app.MaharashtraButton.ButtonPushedFcn = createCallbackFcn(app, @MaharashtraButtonPushed, true);
            app.MaharashtraButton.Text = 'Maharashtra';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'ap.jpg';
            app.AndhrapradeshButton = uibutton(app.UIFigure, 'push');
            app.AndhrapradeshButton.ButtonPushedFcn = createCallbackFcn(app, @AndhrapradeshButtonPushed, true);
            app.AndhrapradeshButton.Text = 'Andhrapradesh';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'bihar.jpg';
            app.BiharButton = uibutton(app.UIFigure, 'push');
            app.BiharButton.ButtonPushedFcn = createCallbackFcn(app, @BiharButtonPushed, true);
            app.BiharButton.Text = 'Bihar';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'Assam.jpg';
            app.AsaamButton = uibutton(app.UIFigure, 'push');
            app.AsaamButton.ButtonPushedFcn = createCallbackFcn(app, @AsaamButtonPushed, true);
            app.AsaamButton.Text = 'Asaam';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'gujrat.jpg';
            app.GujratButton = uibutton(app.UIFigure, 'push');
            app.GujratButton.ButtonPushedFcn = createCallbackFcn(app, @GujratButtonPushed, true);
            app.GujratButton.Text = 'Gujrat';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = 'up.jpg';
            app.UttarpradeshButton = uibutton(app.UIFigure, 'push');
            app.UttarpradeshButton.ButtonPushedFcn = createCallbackFcn(app, @UttarpradeshButtonPushed, true);
            app.UttarpradeshButton.Text = 'Uttarpradesh';
            app.KarnatakaButton = uibutton(app.UIFigure, 'push');
            app.KarnatakaButton.ButtonPushedFcn = createCallbackFcn(app, @KarnatakaButtonPushed, true);
            app.KarnatakaButton.Text = 'Karnataka';
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageSource = 'goa.jpg';
            app.GoaButton = uibutton(app.UIFigure, 'push');
            app.GoaButton.ButtonPushedFcn = createCallbackFcn(app, @GoaButtonPushed, true);
            app.GoaButton.Text = 'Goa';
            app.Image9 = uiimage(app.UIFigure);
            app.Image9.ImageSource = 'panjab.jpeg';
            app.PanjabButton = uibutton(app.UIFigure, 'push');
            app.PanjabButton.ButtonPushedFcn = createCallbackFcn(app, @PanjabButtonPushed, true);
            app.PanjabButton.Text = 'Panjab';
            app.Image10 = uiimage(app.UIFigure);
            app.Image10.ImageSource = 'delhi.jpeg';
            app.DelhiButton = uibutton(app.UIFigure, 'push');
            app.DelhiButton.ButtonPushedFcn = createCallbackFcn(app, @DelhiButtonPushed, true);
            app.DelhiButton.Text = 'Delhi';
            app.Image11 = uiimage(app.UIFigure);
            app.Image11.ImageSource = 'Karnaatak.JPG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fourth

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end