classdef fud < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        RightPanel   matlab.ui.container.Panel
        StartButton  matlab.ui.control.Button
        Image        matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        numclicks % Description
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function startupFcn(app)
            app.JSDataHTML.Data = 0;
            app.numclicks = 0;
        end

        % Callback function
        function plotJSData(app, event)
           data = app.JSDataHTML.Data;
           app.numclicks = app.numclicks + 1;
           
           for k = 1:length(app.numclicks)
               aline = animatedline(app.UIAxes,'MaximumNumPoints',10000,'Marker','*');
               addpoints(aline,app.numclicks,data(k));
               drawnow
           end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            outputFolder = fullfile('FOOD');
rootFolder=fullfile(outputFolder,'caltech');
categories={'Burger','cake','dosa','idli','Frankie','Momos','pizza','Pohe','Samosa','Sandwich','Vadapav'};
imds=imageDatastore(fullfile(rootFolder,categories),'LabelSource','foldernames');
tbl = countEachLabel(imds)
minSetCount = min(tbl{:,2})
imds=splitEachLabel(imds,minSetCount,'randomize');
countEachLabel(imds);
Burger=find(imds.Labels=='Burger',1);
cake=find(imds.Labels=='cake',1);
dosa=find(imds.Labels=='dosa',1);
idli=find(imds.Labels=='idli',1);
Frankie=find(imds.Labels=='Frankie',1);
Momos=find(imds.Labels=='Momos',1);
pizza=find(imds.Labels=='pizza',1);
Pohe=find(imds.Labels=='Pohe',1);
Samosa=find(imds.Labels=='Samosa',1);
Sandwich=find(imds.Labels=='Sandwich',1);
Vadapav=find(imds.Labels=='Vadapav',1);


figure
subplot(2,5,1);
imshow(readimage(imds,Burger));
subplot(2,5,2);
imshow(readimage(imds,cake));
subplot(2,5,3);
imshow(readimage(imds,dosa));
subplot(2,5,4);
imshow(readimage(imds,idli));
subplot(2,5,5);
imshow(readimage(imds,Frankie));
subplot(2,5,6);
imshow(readimage(imds,Momos));
subplot(2,5,7);
imshow(readimage(imds,pizza));
subplot(2,5,8);
imshow(readimage(imds,Samosa));
subplot(2,5,9);
imshow(readimage(imds,Sandwich));
subplot(2,5,10);
imshow(readimage(imds,Vadapav));
net=resnet50();
figure
plot(net)
title('Architecture of ResNet-50')
set(gca,'YLim',[150 170]);

net.Layers(1)
net.Layers(end)

numel(net.Layers(end).ClassNames)
[trainingSet,testSet] = splitEachLabel(imds,0.3,'randomize');

imageSize = net.Layers(1).InputSize;

augmentedTrainingSet = augmentedImageDatastore(imageSize,trainingSet,'ColorPreprocessing','gray2rgb')

augmentedTestSet = augmentedImageDatastore(imageSize,testSet,'ColorPreprocessing','gray2rgb')

w1 = net.Layers(2).Weights;
w1 = mat2gray(w1);

figure
montage(w1)
title('First Convolutional Layer Weights')

featureLayer = 'fc1000';
trainingFeatures = activations(net,augmentedTrainingSet,featureLayer,'MiniBatchSize',32,'OutputAs','columns');

trainingLabels = trainingSet.Labels;
classifier = fitcecoc(trainingFeatures,trainingLabels, 'Learner','Linear','Coding','onevsall','ObservationsIn','columns');

testFeatures = activations(net,augmentedTestSet,featureLayer,'MiniBatchSize',32,'OutputAs','columns');

predictLabels = predict(classifier,testFeatures,'ObservationsIn','columns');

testLabels = testSet.Labels;
confMat = confusionmat(testLabels,predictLabels);
confMat = bsxfun(@rdivide,confMat,sum(confMat,2));

mean(diag(confMat));
[fname, path]=uigetfile('.png','provide an Image for testing');
fname=strcat(path, fname);
newImage =imread(fname);


ds = augmentedImageDatastore(imageSize,newImage,'ColorPreprocessing','gray2rgb');

imageFeatures = activations(net,ds,featureLayer,'MiniBatchSize',32,'OutputAs','columns');

label = predict(classifier,imageFeatures,'ObservationsIn','columns');
sprintf('The loaded image belongs to %s class',label)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {423, 423};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'FOOD IDENTIFICATION USING CNN';
            app.StartButton = uibutton(app.RightPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'foood.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fud

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end