classdef gujrat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image                    matlab.ui.control.Image
        UIAxes                   matlab.ui.control.UIAxes
        RecognitionButton        matlab.ui.control.Button
        YourfoodisTextAreaLabel  matlab.ui.control.Label
        YourfoodisTextArea       matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecognitionButton
        function RecognitionButtonPushed(app, event)
outputFolder = fullfile('FOOD');
rootFolder=fullfile(outputFolder,'Gujrat');
categories={'dabeli','dhokla','jalebi','khakra','khandvi'};
imds=imageDatastore(fullfile(rootFolder,categories),'LabelSource','foldernames');
tbl = countEachLabel(imds);
minSetCount = min(tbl{:,2});
maxNumImages=5;
minSetCount=min(maxNumImages,minSetCount);
imds=splitEachLabel(imds,minSetCount,'randomize');
countEachLabel(imds);
dabeli=find(imds.Labels=='dabeli',1);
dhokla=find(imds.Labels=='dhokla',1);
jalebi=find(imds.Labels=='jalebi',1);
khakra=find(imds.Labels=='khakra',1);
khandvi=find(imds.Labels=='khandvi',1);


net=resnet50();

set(gca,'YLim',[150 170]);

net.Layers(1)
net.Layers(end)

numel(net.Layers(end).ClassNames)
[trainingSet,testSet] = splitEachLabel(imds,0.3,'randomize');

imageSize = net.Layers(1).InputSize;

augmentedTrainingSet = augmentedImageDatastore(imageSize,trainingSet,'ColorPreprocessing','gray2rgb')

augmentedTestSet = augmentedImageDatastore(imageSize,testSet,'ColorPreprocessing','gray2rgb')

w1 = net.Layers(2).Weights;
w1 = mat2gray(w1);


featureLayer = 'fc1000';
trainingFeatures = activations(net,augmentedTrainingSet,featureLayer,'MiniBatchSize',64,'OutputAs','columns');

trainingLabels = trainingSet.Labels;
classifier = fitcecoc(trainingFeatures,trainingLabels, 'Learner','Linear','Coding','onevsall','ObservationsIn','columns');

testFeatures = activations(net,augmentedTestSet,featureLayer,'MiniBatchSize',64,'OutputAs','columns');

predictLabels = predict(classifier,testFeatures,'ObservationsIn','columns');

testLabels = testSet.Labels;
confMat = confusionmat(testLabels,predictLabels);
confMat = bsxfun(@rdivide,confMat,sum(confMat,2));

mean(diag(confMat));
 [fname, path]=uigetfile('.png','provide an Image for testing');
  fname=strcat(path, fname);
 newImage =imread(fname);
imshow(newImage, 'parent',  app.UIAxes);
ds = augmentedImageDatastore(imageSize,newImage,'ColorPreprocessing','gray2rgb');

imageFeatures = activations(net,ds,featureLayer,'MiniBatchSize',32,'OutputAs','columns');

label = predict(classifier,imageFeatures,'ObservationsIn','columns');
app.YourfoodisTextArea  .Value = label;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'f6.jpg';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Your food')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.RecognitionButton = uibutton(app.UIFigure, 'push');
            app.RecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @RecognitionButtonPushed, true);
            app.RecognitionButton.Text = 'Recognition';
            app.YourfoodisTextAreaLabel = uilabel(app.UIFigure);
            app.YourfoodisTextAreaLabel.Text = 'Your food is :';
            app.YourfoodisTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gujrat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end