classdef second < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        YourFoodisTextAreaLabel   matlab.ui.control.Label
        YourFoodisTextArea        matlab.ui.control.TextArea
        ImagepreprocessingButton  matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImagepreprocessingButton
        function ImagepreprocessingButtonPushed(app, event)
outputFolder = fullfile('FOOD');
rootFolder=fullfile(outputFolder,'caltech');
categories={'Burger','cake','dosa','idli','Frankie','Momos','pizza','Pohe','Samosa','Sandwich','Vadapav'};
imds=imageDatastore(fullfile(rootFolder,categories),'LabelSource','foldernames');
tbl = countEachLabel(imds)
minSetCount = min(tbl{:,2})
imds=splitEachLabel(imds,minSetCount,'randomize');
countEachLabel(imds);
Burger=find(imds.Labels=='Burger',1);
cake=find(imds.Labels=='cake',1);
dosa=find(imds.Labels=='dosa',1);
idli=find(imds.Labels=='idli',1);
Frankie=find(imds.Labels=='Frankie',1);
Momos=find(imds.Labels=='Momos',1);
pizza=find(imds.Labels=='pizza',1);
Pohe=find(imds.Labels=='Pohe',1);
Samosa=find(imds.Labels=='Samosa',1);
Sandwich=find(imds.Labels=='Sandwich',1);
Vadapav=find(imds.Labels=='Vadapav',1);


figure
subplot(2,5,1);
imshow(readimage(imds,Burger));
subplot(2,5,2);
imshow(readimage(imds,cake));
subplot(2,5,3);
imshow(readimage(imds,dosa));
subplot(2,5,4);
imshow(readimage(imds,idli));
subplot(2,5,5);
imshow(readimage(imds,Frankie));
subplot(2,5,6);
imshow(readimage(imds,Momos));
subplot(2,5,7);
imshow(readimage(imds,pizza));
subplot(2,5,8);
imshow(readimage(imds,Samosa));
subplot(2,5,9);
imshow(readimage(imds,Sandwich));
subplot(2,5,10);
imshow(readimage(imds,Vadapav));
net=resnet50();
figure
plot(net)
title('Architecture of ResNet-50')
set(gca,'YLim',[150 170]);

net.Layers(1)
net.Layers(end)

numel(net.Layers(end).ClassNames)
[trainingSet,testSet] = splitEachLabel(imds,0.3,'randomize');

imageSize = net.Layers(1).InputSize;

augmentedTrainingSet = augmentedImageDatastore(imageSize,trainingSet,'ColorPreprocessing','gray2rgb')

augmentedTestSet = augmentedImageDatastore(imageSize,testSet,'ColorPreprocessing','gray2rgb')

w1 = net.Layers(2).Weights;
w1 = mat2gray(w1);

figure
montage(w1)
title('First Convolutional Layer Weights')

featureLayer = 'fc1000';
trainingFeatures = activations(net,augmentedTrainingSet,featureLayer,'MiniBatchSize',32,'OutputAs','columns');

trainingLabels = trainingSet.Labels;
classifier = fitcecoc(trainingFeatures,trainingLabels, 'Learner','Linear','Coding','onevsall','ObservationsIn','columns');

testFeatures = activations(net,augmentedTestSet,featureLayer,'MiniBatchSize',32,'OutputAs','columns');

predictLabels = predict(classifier,testFeatures,'ObservationsIn','columns');

testLabels = testSet.Labels;
confMat = confusionmat(testLabels,predictLabels);
confMat = bsxfun(@rdivide,confMat,sum(confMat,2));

mean(diag(confMat));
 [fname, path]=uigetfile('.png','provide an Image for testing');
  fname=strcat(path, fname);
 newImage =imread(fname);
imshow(newImage, 'parent',  app.UIAxes);
ds = augmentedImageDatastore(imageSize,newImage,'ColorPreprocessing','gray2rgb');

imageFeatures = activations(net,ds,featureLayer,'MiniBatchSize',32,'OutputAs','columns');

label = predict(classifier,imageFeatures,'ObservationsIn','columns');
app.YourFoodisTextArea .Value = label;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.YourFoodisTextAreaLabel = uilabel(app.UIFigure);
            app.YourFoodisTextAreaLabel.Text = 'Your Food is';
            app.YourFoodisTextArea = uitextarea(app.UIFigure);
            app.ImagepreprocessingButton = uibutton(app.UIFigure, 'push');
            app.ImagepreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @ImagepreprocessingButtonPushed, true);
            app.ImagepreprocessingButton.Text = 'Image preprocessing';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image Of Your Food ')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = second

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end